classdef AnalizatorDMC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        KontrolawykresemButtonGroup  matlab.ui.container.ButtonGroup
        ToutFlowInSwitch             matlab.ui.control.Switch
        WybrwykresuDropDown          matlab.ui.control.DropDown
        WybrwykresuDropDownLabel     matlab.ui.control.Label
        PoprzedniprzebiegButton      matlab.ui.control.Button
        NastpnyprzebiegButton        matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function PlotAxes(app, buttonTypePressed, jumpTo)
            persistent it % iterator
            if isempty(it)
                it = 1;
            end

            if ~exist('buttonTypePressed','var')
                % third parameter does not exist, so default it to something
                buttonTypePressed = "none";
            end
            if ~exist('jumpTo','var')
                % third parameter does not exist, so default it to something
                jumpTo = NaN;
            else
                it = jumpTo;
            end

            files = dir('.\*.csv');
            fullpaths = fullfile({files.folder}, {files.name});

            if(strcmp(buttonTypePressed, "increment"))
                it = it + 1;
            elseif(strcmp(buttonTypePressed, "decrement"))
                it = it - 1;
            end

            if it <= 1
                it = 1;
            end

            if(it >= length(fullpaths))
                it = length(fullpaths);
            end

            path = string(fullpaths(it));
            [pathstr, name, ext] = fileparts(fullpaths(it));
            pomiar = readtable( path, 'NumHeaderLines', 1 ); % Odczyt z pliku csv

            pomiarWidth = width(pomiar);
            % Podział tabeli na parametry
            varIndexOutOfRange = false;
            try
                sampleNo = table2array(pomiar(:,1));
                time = table2array(pomiar(:,2));
                flowIn_SP = table2array(pomiar(:,3));
                flowIn = table2array(pomiar(:,4));
                heaterTOUT = table2array(pomiar(:,5));
                heaterTOUT_SP = table2array(pomiar(:,6));
                commError = table2array(pomiar(:,7));
            catch ME
                disp(ME.identifier);
                varIndexOutOfRange = true;
            end

            % Skalowanie zmiennych do ich fizycznych wartosci rzeczywistych:
            sampleNo = 1:1:length(sampleNo);
            sampleNo = sampleNo / 10;

            if strcmp(app.ToutFlowInSwitch.Value, 'Tout') % rysuj plot Tout
                ax = app.UIAxes;
                cla(ax); % czysci wykres
                grid(ax, 'off');
                title(ax, name, 'Interpreter', 'none');
                grid(ax, 'minor');
                hold(ax, 'on');
                plot(ax, sampleNo, heaterTOUT_SP, 'LineWidth', 1.2);
                plot(ax, sampleNo, heaterTOUT, 'LineWidth', 1.2);
%                 if varIndexOutOfRange == false
%                     plot(ax, sampleNo, commError, 'LineWidth', 1.2);
%                 end
                hold(ax, 'off');
               legend(ax, ['T_{out} SetPoint[ C' char(176) ' ]'],...
                ['T_{out} [ C' char(176) ' ]'], 'Błąd komunikacji [True/False]',...
                'Location', 'best');
               ylim(ax, [0 max(heaterTOUT)+0.5]);

            else % rysuj plot FlowIn
                ax = app.UIAxes;
                cla(ax); % czysci wykres
                grid(ax, 'off');
                title(ax, name, 'Interpreter', 'none');
                grid(ax, 'minor');
                hold(ax, 'on');
                plot(ax, sampleNo, flowIn_SP, 'LineWidth', 1.2);
                plot(ax, sampleNo, flowIn, 'LineWidth', 1.2);
%                 if varIndexOutOfRange == false
%                     plot(ax, sampleNo, commError, 'LineWidth', 1.2);
%                 end
                hold(ax, 'off');
                legend(ax, 'Przeplyw F_{in} SetPoint [ l ]', 'Przeplyw F_{in} [ l ]', 'Błąd komunikacji [True/False]',...
                'Location', 'best');
                ylim(ax, [0 max(flowIn)+0.5]);
            end


            % Format plot:
            xlim(ax, [0 sampleNo(end)]);


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Addpath:
            addpath('.\');
            % Drop down init:
            dropDown = app.WybrwykresuDropDown;
            dropDown.Items
            files = dir('.\*.csv');
            fullpaths = fullfile({files.folder}, {files.name});
            for i=1:1:length(fullpaths)
                [~, name, ~] = fileparts(fullpaths(i));
                dropDownString(i) = string(name);
            end
            dropDown.Items = dropDownString;
        end

        % Button down function: KontrolawykresemButtonGroup
        function KontrolawykresemButtonGroupButtonDown(app, event)

        end

        % Button pushed function: NastpnyprzebiegButton
        function NastpnyprzebiegButtonPushed(app, event)
            PlotAxes(app, "increment");
        end

        % Button pushed function: PoprzedniprzebiegButton
        function PoprzedniprzebiegButtonPushed(app, event)
            PlotAxes(app, "decrement");
        end

        % Drop down opening function: WybrwykresuDropDown
        function WybrwykresuDropDownOpening(app, event)
            % Drop down init:
            dropDown = app.WybrwykresuDropDown;
            dropDown.Items
            files = dir('.\*.csv');
            fullpaths = fullfile({files.folder}, {files.name});
            for i=1:1:length(fullpaths)
                [~, name, ~] = fileparts(fullpaths(i));
                dropDownString(i) = string(name);
            end
            dropDown.Items = dropDownString;
        end

        % Value changed function: WybrwykresuDropDown
        function WybrwykresuDropDownValueChanged(app, event)
            value = app.WybrwykresuDropDown.Value;
            dropDown = app.WybrwykresuDropDown;
            index = find(strcmp(dropDown.Items, value));
            PlotAxes(app,'none', index);
        end

        % Callback function
        function ChkaStatycznaSwitchValueChanged(app, event)
            value = app.ChkaStatycznaSwitch.Value;
            ax = app.UIAxes;
            currentPlots = ax.Children;
            setPointPlot = currentPlots(3);
            setPointPlotYData = setPointPlot.YData;
            smoothSetPointYData = round(setPointPlotYData);
            len = length(setPointPlotYData);
            nearVals = 0;
            for i=2:1:len
                if setPointPlotYData(i) ~= setPointPlotYData(i-1)
                    try
                        nearVals = setPointPlotYData(i-30:i+30);
                    catch ME
                        if strcmp(ME.message,'Array indices must be positive integers or logical values.')
                            nearVals = setPointPlotYData(i:i+50);
                        else
                            nearVals = setPointPlotYData(i-50:i);
                        end

                    end
                    smoothenedVal = round(mean(nearVals));
                    setPointPlotYData(i) = smoothenedVal;
                end
            end

            app.UIAxes.Children(3).YData = smoothSetPointYData;


        end

        % Value changed function: ToutFlowInSwitch
        function ToutFlowInSwitchValueChanged(app, event)
            value = app.ToutFlowInSwitch.Value;
            valueDropDown = app.WybrwykresuDropDown.Value;
            dropDown = app.WybrwykresuDropDown;
            index = find(strcmp(dropDown.Items, valueDropDown));
            PlotAxes(app,'none', index);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Przebiegi')
            xlabel(app.UIAxes, 't [s]')
            ylabel(app.UIAxes, 'Wartości')
            zlabel(app.UIAxes, 'Z')
            app.KontrolawykresemButtonGroup = uibuttongroup(app.GridLayout);
            app.KontrolawykresemButtonGroup.Title = 'Kontrola wykresem';
            app.KontrolawykresemButtonGroup.ButtonDownFcn = createCallbackFcn(app, @KontrolawykresemButtonGroupButtonDown, true);
            app.NastpnyprzebiegButton = uibutton(app.KontrolawykresemButtonGroup, 'push');
            app.NastpnyprzebiegButton.ButtonPushedFcn = createCallbackFcn(app, @NastpnyprzebiegButtonPushed, true);
            app.NastpnyprzebiegButton.Text = 'Następny przebieg';
            app.PoprzedniprzebiegButton = uibutton(app.KontrolawykresemButtonGroup, 'push');
            app.PoprzedniprzebiegButton.ButtonPushedFcn = createCallbackFcn(app, @PoprzedniprzebiegButtonPushed, true);
            app.PoprzedniprzebiegButton.Text = 'Poprzedni przebieg';
            app.WybrwykresuDropDownLabel = uilabel(app.KontrolawykresemButtonGroup);
            app.WybrwykresuDropDownLabel.Text = 'Wybór wykresu';
            app.WybrwykresuDropDown = uidropdown(app.KontrolawykresemButtonGroup);
            app.WybrwykresuDropDown.DropDownOpeningFcn = createCallbackFcn(app, @WybrwykresuDropDownOpening, true);
            app.WybrwykresuDropDown.ValueChangedFcn = createCallbackFcn(app, @WybrwykresuDropDownValueChanged, true);
            app.ToutFlowInSwitch = uiswitch(app.KontrolawykresemButtonGroup, 'slider');
            app.ToutFlowInSwitch.Items = {'Tout', 'FlowIn'};
            app.ToutFlowInSwitch.ValueChangedFcn = createCallbackFcn(app, @ToutFlowInSwitchValueChanged, true);
            app.ToutFlowInSwitch.Value = 'Tout';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalizatorDMC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end